classdef adjudicator_app_DRAFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TopBarGridLayout               matlab.ui.container.GridLayout
        SaveProgressExitButton         matlab.ui.control.Button
        ReviewPreviousDecisionButton   matlab.ui.control.Button
        AdjudicationTaskTextArea       matlab.ui.control.TextArea
        AdjudicationTaskTextAreaLabel  matlab.ui.control.Label
        ControlPanelGridLayout         matlab.ui.container.GridLayout
        SpectrogramControlPanel        matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        DynamicRangedBEditField        matlab.ui.control.NumericEditField
        DynamicRangedBEditFieldLabel   matlab.ui.control.Label
        FFTSizepointsEditField         matlab.ui.control.NumericEditField
        FFTSizepointsEditFieldLabel    matlab.ui.control.Label
        WindowOverlapPercentEditField  matlab.ui.control.NumericEditField
        WindowOverlapPercentEditFieldLabel  matlab.ui.control.Label
        WindowSizesecondsEditField     matlab.ui.control.NumericEditField
        WindowSizesecondsEditFieldLabel  matlab.ui.control.Label
        DecisionPanel                  matlab.ui.container.Panel
        CallChorusAbsentButton         matlab.ui.control.Button
        DiscreteCallsChorusPresentButton  matlab.ui.control.Button
        ChorusPresentButton            matlab.ui.control.Button
        DiscreteCallsPresentButton     matlab.ui.control.Button
        SpectrogramPanel               matlab.ui.container.Panel
        TimeZoomSlider                 matlab.ui.control.RangeSlider
        FreqZoomSlider                 matlab.ui.control.RangeSlider
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that store app data
    properties (Access = private)
        DisagreementsData     % Structure containing the disagreements data
        CurrentIndex = 1      % Current adjudication index
        DataLoaded = false    % Flag indicating if data is loaded
        DataModified = false  % Flag indicating if data has been modified
        DataFilePath          % Path to the loaded data file
        AudioFs               % Sample rate of current audio
        FullTimeRange         % Full time range for the current spectrogram
        FullFreqRange         % Full frequency range for the current spectrogram
    end

    % App Functions
    methods (Access = private)
        function loadDisagreementsData(app, filePath)
            % Loads the disagreement data file, either automatically finding it
            % or prompting the user to select it

            try
                if nargin < 2 || isempty(filePath)
                    % Look for files with the expected pattern in the current directory
                    appPath = fileparts(mfilename('fullpath'));
                    filePattern = fullfile(appPath, 'detectionsToAdjudicate_*.mat');
                    files = dir(filePattern);
                    
                    if isempty(files)
                        % No files found, prompt user to locate
                        [fileName, filePath] = uigetfile('*.mat', 'Select disagreements data file');
                        if isequal(fileName, 0)
                            % User canceled
                            uialert(app.UIFigure, 'No data file selected. App will close.', 'Warning');
                            delete(app);
                            return;
                        end
                        filePath = fullfile(filePath, fileName);
                    else
                        % Use the most recent file
                        [~, idx] = sort([files.datenum], 'descend');
                        filePath = fullfile(files(idx(1)).folder, files(idx(1)).name);
                    end
                end
                
                % Load the data
                data = load(filePath);
                if ~isfield(data, 'disagreements')
                    error('Selected file does not contain disagreements data');
                end
                
                app.DisagreementsData = data.disagreements;
                app.DataFilePath = filePath;
                app.DataLoaded = true;
                
                % Find first unadjudicated item
                app.CurrentIndex = 1;
                for i = 1:length(app.DisagreementsData)
                    if isempty(app.DisagreementsData(i).analystDecision)
                        app.CurrentIndex = i;
                        break;
                    end
                end
                
                % Update UI
                updateAdjudicationDisplay(app);
            catch ex
                uialert(app.UIFigure, ['Error loading data: ' ex.message], 'Error');
            end
        end
        
        function updateSpectrogram(app)
            % Updates the spectrogram display based on current settings and audio
          
            if ~app.DataLoaded || app.CurrentIndex > length(app.DisagreementsData)
                return;
            end
            
            % Get current audio data
            currentAudio = app.DisagreementsData(app.CurrentIndex).audio;
            app.AudioFs = app.DisagreementsData(app.CurrentIndex).audioFs;
            
            % DC filter and normalize
            currentAudio = currentAudio - mean(currentAudio);
            currentAudio = currentAudio / max(abs(currentAudio));
            
            % Get spectrogram parameters
            windowSizeSec = app.WindowSizesecondsEditField.Value;
            windowSizeSamples = round(windowSizeSec * app.AudioFs);
            overlap = app.WindowOverlapPercentEditField.Value / 100;
            overlapSamples = round(windowSizeSamples * overlap);
            nfft = app.FFTSizepointsEditField.Value;
            dynamicRange = app.DynamicRangedBEditField.Value;
            
            % Ensure window size is valid
            if windowSizeSamples > length(currentAudio)
                windowSizeSamples = length(currentAudio);
                app.WindowSizesecondsEditField.Value = windowSizeSamples / app.AudioFs;
            end
            
            % Compute spectrogram
            [s, f, t] = spectrogram(currentAudio, windowSizeSamples, overlapSamples, nfft, app.AudioFs, 'yaxis');
            
            % Convert to power in dB
            s_display = pow2db((abs(s).^2) + eps);
            
            % Set color limits
            cMax = max(s_display(:));
            cMin = cMax - dynamicRange;
            
            % Store for slider adjustments
            app.FullTimeRange = [min(t), max(t)];
            app.FullFreqRange = [min(f), max(f)];
            
            % Display spectrogram
            cla(app.UIAxes);
            imagesc(app.UIAxes, t, f, s_display);
            colormap(app.UIAxes, 'parula');
            colorbar(app.UIAxes);
            clim(app.UIAxes, [cMin, cMax]);
            
            % Set axes properties
            app.UIAxes.YDir = 'normal';
            xlim(app.UIAxes, app.FullTimeRange);
            ylim(app.UIAxes, app.FullFreqRange);
            
            % Update sliders
            app.TimeZoomSlider.Limits = app.FullTimeRange;
            app.TimeZoomSlider.Value = app.FullTimeRange;
            app.FreqZoomSlider.Limits = app.FullFreqRange;
            app.FreqZoomSlider.Value = app.FullFreqRange;
            
            % Set axes labels
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Frequency (Hz)');
            title(app.UIAxes, 'Spectrogram (Power/Frequency, dB)');
            grid(app.UIAxes, 'on');
        end
        
        function updateAdjudicationDisplay(app)
            % Updates the display for the current adjudication task
     
            if ~app.DataLoaded
                return;
            end
            
            % Update adjudication task number
            app.AdjudicationTaskTextArea.Value = sprintf('Adjudication %d of %d', app.CurrentIndex, length(app.DisagreementsData));
            
            % Update spectrogram
            updateSpectrogram(app);
            
            % Enable/disable review button
            app.ReviewPreviousDecisionButton.Enable = (app.CurrentIndex > 1);
        end
        
        function saveProgress(app)
            % Saves the current progress to the data file
            %
            % Ben Jancovich, 2024
            % Centre for Marine Science and Innovation
            % School of Biological, Earth and Environmental Sciences
            % University of New South Wales, Sydney, Australia
            %
            if ~app.DataLoaded || ~app.DataModified
                return;
            end
            
            try
                % Load the entire original file to preserve all variables
                originalData = load(app.DataFilePath);
                
                % Update only the disagreements structure
                originalData.disagreements = app.DisagreementsData;
                
                % Save all variables back to the file
                save(app.DataFilePath, '-struct', 'originalData', '-v7.3');
                
                app.DataModified = false;
            catch ex
                uialert(app.UIFigure, ['Error saving progress: ' ex.message], 'Error');
            end
        end
        
        function decisionButtonCallback(app, decision)
            % Handles decision button clicks
  
            if ~app.DataLoaded || app.CurrentIndex > length(app.DisagreementsData)
                return;
            end
            
            % Record decision
            app.DisagreementsData(app.CurrentIndex).analystDecision = decision;
            app.DataModified = true;
            
            % Save progress
            saveProgress(app);
            
            % Move to next unadjudicated item if available
            if app.CurrentIndex < length(app.DisagreementsData)
                app.CurrentIndex = app.CurrentIndex + 1;
                updateAdjudicationDisplay(app);
            else
                % All items adjudicated
                msg = 'All disagreements have been adjudicated!';
                uialert(app.UIFigure, msg, 'Complete', 'Icon', 'success');
            end
        end
  
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load data file at startup
            loadDisagreementsData(app);
        end

        % Button pushed function: DiscreteCallsPresentButton
        function DiscreteCallsPresentButtonPushed(app, event)
            decisionButtonCallback(app, "DiscreteCallsPresent");
        end

        % Button pushed function: ChorusPresentButton
        function ChorusPresentButtonPushed(app, event)
            decisionButtonCallback(app, "ChorusPresent");
        end

        % Button pushed function: DiscreteCallsChorusPresentButton
        function DiscreteCallsChorusPresentButtonPushed(app, event)
            decisionButtonCallback(app, "DiscreteCallsChorusPresent");
        end

        % Button pushed function: CallChorusAbsentButton
        function CallChorusAbsentButtonPushed(app, event)
            decisionButtonCallback(app, "CallChorusAbsent");
        end

        % Button pushed function: ReviewPreviousDecisionButton
        function ReviewPreviousDecisionButtonPushed(app, event)
            if app.CurrentIndex > 1
                app.CurrentIndex = app.CurrentIndex - 1;
                updateAdjudicationDisplay(app);
            end
        end

        % Button pushed function: SaveProgressExitButton
        function SaveProgressExitButtonPushed(app, event)
            saveProgress(app);
            delete(app);
        end

        % Value changed function: TimeZoomSlider
        function TimeZoomSliderValueChanged(app, event)
            xlim(app.UIAxes, app.TimeZoomSlider.Value);
        end

        % Value changed function: FreqZoomSlider
        function FreqZoomSliderValueChanged(app, event)
            ylim(app.UIAxes, app.FreqZoomSlider.Value);
        end

        % Value changed function: WindowSizesecondsEditField
        function WindowSizesecondsEditFieldValueChanged(app, event)
            updateSpectrogram(app);
        end

        % Value changed function: WindowOverlapPercentEditField
        function WindowOverlapPercentEditFieldValueChanged(app, event)
            updateSpectrogram(app);
        end

        % Value changed function: FFTSizepointsEditField
        function FFTSizepointsEditFieldValueChanged(app, event)
            updateSpectrogram(app);
        end

        % Value changed function: DynamicRangedBEditField
        function DynamicRangedBEditFieldValueChanged(app, event)
            updateSpectrogram(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            saveProgress(app);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SpectrogramPanel = uipanel(app.GridLayout);
            app.SpectrogramPanel.Title = 'Spectrogram';
            app.UIAxes = uiaxes(app.SpectrogramPanel);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Frequency (Hz)')
            zlabel(app.UIAxes, 'Power (dB)')
            app.FreqZoomSlider = uislider(app.SpectrogramPanel, 'range');
            app.FreqZoomSlider.ValueChangedFcn = createCallbackFcn(app, @FreqZoomSliderValueChanged, true);
            app.TimeZoomSlider = uislider(app.SpectrogramPanel, 'range');
            app.TimeZoomSlider.ValueChangedFcn = createCallbackFcn(app, @TimeZoomSliderValueChanged, true);
            app.ControlPanelGridLayout = uigridlayout(app.GridLayout);
            app.DecisionPanel = uipanel(app.ControlPanelGridLayout);
            app.DecisionPanel.Title = 'Decision';
            app.DiscreteCallsPresentButton = uibutton(app.DecisionPanel, 'push');
            app.DiscreteCallsPresentButton.ButtonPushedFcn = createCallbackFcn(app, @DiscreteCallsPresentButtonPushed, true);
            app.DiscreteCallsPresentButton.Text = 'Discrete Call(s) Present';
            app.ChorusPresentButton = uibutton(app.DecisionPanel, 'push');
            app.ChorusPresentButton.ButtonPushedFcn = createCallbackFcn(app, @ChorusPresentButtonPushed, true);
            app.ChorusPresentButton.Text = 'Chorus Present';
            app.DiscreteCallsChorusPresentButton = uibutton(app.DecisionPanel, 'push');
            app.DiscreteCallsChorusPresentButton.ButtonPushedFcn = createCallbackFcn(app, @DiscreteCallsChorusPresentButtonPushed, true);
            app.DiscreteCallsChorusPresentButton.Text = 'Discrete Call(s) & Chorus Present';
            app.CallChorusAbsentButton = uibutton(app.DecisionPanel, 'push');
            app.CallChorusAbsentButton.ButtonPushedFcn = createCallbackFcn(app, @CallChorusAbsentButtonPushed, true);
            app.CallChorusAbsentButton.Text = 'Call & Chorus Absent';
            app.SpectrogramControlPanel = uipanel(app.ControlPanelGridLayout);
            app.SpectrogramControlPanel.Title = 'Spectrogram Control';
            app.GridLayout4 = uigridlayout(app.SpectrogramControlPanel);
            app.WindowSizesecondsEditFieldLabel = uilabel(app.GridLayout4);
            app.WindowSizesecondsEditFieldLabel.Text = 'Window Size (seconds)';
            app.WindowSizesecondsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.WindowSizesecondsEditField.Limits = [0.01 Inf];
            app.WindowSizesecondsEditField.ValueChangedFcn = createCallbackFcn(app, @WindowSizesecondsEditFieldValueChanged, true);
            app.WindowSizesecondsEditField.Value = 1;
            app.WindowOverlapPercentEditFieldLabel = uilabel(app.GridLayout4);
            app.WindowOverlapPercentEditFieldLabel.Text = 'Window Overlap (Percent)';
            app.WindowOverlapPercentEditField = uieditfield(app.GridLayout4, 'numeric');
            app.WindowOverlapPercentEditField.Limits = [0 99];
            app.WindowOverlapPercentEditField.RoundFractionalValues = 'on';
            app.WindowOverlapPercentEditField.ValueDisplayFormat = '%.0f';
            app.WindowOverlapPercentEditField.ValueChangedFcn = createCallbackFcn(app, @WindowOverlapPercentEditFieldValueChanged, true);
            app.WindowOverlapPercentEditField.Value = 85;
            app.FFTSizepointsEditFieldLabel = uilabel(app.GridLayout4);
            app.FFTSizepointsEditFieldLabel.Text = 'FFT Size (points)';
            app.FFTSizepointsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.FFTSizepointsEditField.Limits = [64 10240];
            app.FFTSizepointsEditField.RoundFractionalValues = 'on';
            app.FFTSizepointsEditField.ValueChangedFcn = createCallbackFcn(app, @FFTSizepointsEditFieldValueChanged, true);
            app.FFTSizepointsEditField.Value = 2048;
            app.DynamicRangedBEditFieldLabel = uilabel(app.GridLayout4);
            app.DynamicRangedBEditFieldLabel.Text = 'Dynamic Range (dB)';
            app.DynamicRangedBEditField = uieditfield(app.GridLayout4, 'numeric');
            app.DynamicRangedBEditField.Limits = [0 Inf];
            app.DynamicRangedBEditField.ValueChangedFcn = createCallbackFcn(app, @DynamicRangedBEditFieldValueChanged, true);
            app.DynamicRangedBEditField.Value = 40;
            app.TopBarGridLayout = uigridlayout(app.GridLayout);
            app.AdjudicationTaskTextAreaLabel = uilabel(app.TopBarGridLayout);
            app.AdjudicationTaskTextAreaLabel.Text = 'Adjudication Task #';
            app.AdjudicationTaskTextArea = uitextarea(app.TopBarGridLayout);
            app.ReviewPreviousDecisionButton = uibutton(app.TopBarGridLayout, 'push');
            app.ReviewPreviousDecisionButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewPreviousDecisionButtonPushed, true);
            app.ReviewPreviousDecisionButton.Text = 'Review Previous Decision';
            app.SaveProgressExitButton = uibutton(app.TopBarGridLayout, 'push');
            app.SaveProgressExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProgressExitButtonPushed, true);
            app.SaveProgressExitButton.Text = 'Save Progress & Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adjudicator_app_DRAFT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end